classdef RandomVariableApp < matlab.apps.AppBase
    %RANDOMVARIABLEAPP This app illustrates the concept of a random
    %variable and its distribution.
    %   A random variable assigns a measurable value to every outcome of a
    %   random experiment or process.In this app, you can create a random
    %   variable by assigning outcomes from an abstract porbability space
    %   to a real number. You can generate an empirical distribution of
    %   this random variable by sampling the probability space, also called
    %   Monte Carlo sampling.
    %   
    %   The app includes a predefined random variable that you can load by
    %   clicking the "Load π example" button. This creates an inscribed
    %   circular region in the square probability space. The variable takes
    %   a value of 4 within this circle and 0 outside. Study the variable
    %   and its distribution to estimate the value of the constant π.

    % Properties that correspond to app components
    properties (Access = public)
        RandVarFig             matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        ShowParams             matlab.ui.control.CheckBox
        EnterLabel             matlab.ui.control.Label
        PiButton               matlab.ui.control.Button
        ColumnsEditField       matlab.ui.control.NumericEditField
        ColumnsEditFieldLabel  matlab.ui.control.Label
        RowsEditField          matlab.ui.control.NumericEditField
        RowsEditFieldLabel     matlab.ui.control.Label
        nEditFieldLabel        matlab.ui.control.Label
        nEditField             matlab.ui.control.NumericEditField
        ResetButton            matlab.ui.control.Button
        ProbabilityspaceLabel  matlab.ui.control.Label
        StopButton             matlab.ui.control.Button
        TextArea               matlab.ui.control.TextArea
        SampleButton           matlab.ui.control.Button
        patchAx                matlab.ui.control.UIAxes
        histAx                 matlab.ui.control.UIAxes
        probAx                 matlab.ui.control.UIAxes
    end


    properties (Access = private)
        cmat               % Matrix of color indices to be displayed on grid
        gridSize           % Size of the grid as a two-element vector of rows and columns
        allVals            % A vector of all the existing values in the sample space
        probIm             % Handle to the probability image
        stopFlag           % Logical flag to stop sampling
        legHG              % Handle to legend
        pointsHG           % Handle to all sample points
        histHG             % Handle to the histogram plot
        txtHG              % Handle to the text labels above the histogram bars
        patchHG = gobjects % Handle to the hidden patches of colors used in the legend
        clickFlag          % Logical flag to indicate clicks on the sample space grid
        cmap = [0        1            0          % Colors in the order that they are used for new values
            0            0            1
            1            0            0
            0         0.97            1
            1         0.48         0.86
            1         0.86         0.48
            0         0.55            1
            0         0.41         0.03
         0.62         0.28         0.21
         0.24            0         0.41
            1         0.93            1
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         0.59         0.38         0.28]
    end

    methods (Access = private)

        function viewOutcomes(app,M,map)

            % Generate the x and y coordinates for the center of the cells (from 1 to N).
            [N1,N2] = size(M);
            ax = app.probAx;

            % Plot the input matrix as an indexed image with a color map (M,MAP).
            app.probIm = imagesc(ax,M,"CDataMapping","scaled",[1,length(app.cmap)]);
            colormap(ax,map)
            alpha(app.probIm,0.45);

            % Set the axes properties for readability.
            ax.FontSize = 18;
            ax.FontWeight = "bold";
            ax.TickLength = [0 0];
            ax.XTick = [];
            ax.YTick = [];
            ax.XAxis.MinorTickValues = 0.5:1:N2;
            ax.YAxis.MinorTickValues = 0.5:1:N1;
            ax.MinorGridLineStyle = "-";
            grid(ax,"on")
            set(ax,"xminorgrid","on","yminorgrid","on")
            ax.GridLineStyle = "none";
            ax.TitleFontSizeMultiplier = 1;
            xlim(ax,[0 N2] + 0.5)
            ylim(ax,[0 N1] + 0.5)

            % Call the function that creates a legend entry for the new
            % value and records its existence.
            newValue(app);

        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.probAx,"equal")
            app.gridSize = [app.RowsEditField.Value,app.ColumnsEditField.Value];
            app.cmat = ones(app.gridSize);
            app.legHG = legend(app.patchAx,"Location","northwestoutside","AutoUpdate","on","FontSize",14,"Box","off","NumColumns",3);
            viewOutcomes(app,app.cmat,app.cmap);
        end

        % Value changed function: nEditField
        function newValue(app, event)
            value = app.nEditField.Value;
            if ~any(app.allVals == value)
                app.allVals(end+1) = value;
                ind = length(app.allVals);
                app.patchHG(end+1) = patch(app.patchAx,NaN,NaN,app.cmap(ind,:),"DisplayName",string(value));
            end

        end

        % Button pushed function: SampleButton
        function sampleDistribution(app, event)
            % Set states of all components before starting sampling
            app.stopFlag = false;
            delete(app.histHG);
            delete(app.txtHG);
            delete(app.pointsHG);
            set(app.legHG,"AutoUpdate","off")
            set(app.ResetButton,"Enable","off")
            set(app.SampleButton,"Enable","off")
            set(app.PiButton,"Enable","off");
            set(app.RowsEditField,"Editable","off")
            set(app.nEditField,"Editable","off");
            set(app.ColumnsEditField,"Editable","off")

            % Initialize variables
            [N1,N2] = size(app.cmat);
            trials = 10000;
            
            % Generate a random sample and assign it
            % to the value corresponding to the outcome containing the sample.
            x = (N1)*rand + 0.5;
            y = (N2)*rand + 0.5;
            numCats = numel(unique(app.cmat));
            out = categorical(app.allVals(app.cmat(:)));
            out(1) = categorical(app.allVals(app.cmat(round(x),round(y))));

            % Create the first iteration of all the graphics (histogram,
            % sample markers, text for histogram bar labels, and subtitles
            % for distribution parameters
            ax = app.histAx;
            app.histHG = histogram(ax,out(1),"Normalization","count","BarWidth",0.1,"FaceColor",[0.5 0.5 0.9],"EdgeColor",[0.5,0.5,0.9]);    % Initialize histogram by including the first outcome
            hold(app.probAx,"on")
            app.pointsHG = plot(app.probAx,y,x,".w","MarkerSize",12,"DisplayName","Samples");
            hold(app.probAx,"off")
            H = app.histHG;
            xtext = 1:numCats;
            ytext = H.Values;
            app.txtHG = text(ax,xtext,ytext,string(ytext'),"vert","bottom","horiz","center");
            app.histAx.Subtitle = text(ax,0,1,["E(X) = " + sum(H.Values.* str2double(cellstr(H.Categories)));""],"FontSize",14);
            set(ax,"Box","off","TickLength",[0 0])
            set(ax,"YTick",[])
            hold(ax,"on")
            % Iterate through the samples and update all graphics.
            for k = 2:trials
                % Add checks to see if the app is still open and if the
                % stop button has been clicked.
                if ~isvalid(app)
                    break
                else
                    if app.stopFlag
                        return
                    end
                end
                
                % Create next sample
                x = (N1)*rand + 0.5;
                y = (N2)*rand + 0.5;

                % Update the image with new sample point
                hold(app.probAx,"on")
                app.pointsHG.XData = [app.pointsHG.XData,y];
                app.pointsHG.YData = [app.pointsHG.YData,x];
                hold(app.probAx,"off")

                % Assign the outcome corresponding to the sample's value
                % and update the histogram
                out(k) = categorical(app.allVals(app.cmat(round(x),round(y))));
                set(H,"Data",out(1:k));
                xtext = 1:length(H.Categories);
                ytext = H.Values;
                ylim(ax,[0,k])
                set(app.txtHG,{'String'},cellstr(string(round((ytext./k),3)')))
                set(app.txtHG,{'Position'},num2cell([xtext;ytext]',2))
                
                % Compute the distribution parameters (expected value and
                % variance, and update subtitle text if the checkbox for
                % parameters is enabled
                X = str2double(cellstr(H.Categories));
                P_X = H.Values./k;
                expX = sum(P_X.*X);
                varX = sum(P_X.*((X - expX).^2));
                if app.ShowParams.Value
                    subStr = [compose("Samples: %6i",k); compose("E[X] = %.3f,   Var[X] = %.3f",expX,varX);" "];
                else
                    subStr = [compose("Samples: %6i",k); " "];
                end
                set(app.histAx.Subtitle,"String",subStr)
                drawnow

            end
            % If the app is open, reset all the graphic components.
            if isvalid(app)         
                hold(ax,"off")
                set(app.legHG,"AutoUpdate","on")
                set(app.ResetButton,"Enable","on")
                set(app.SampleButton,"Enable","on")
                set(app.PiButton,"Enable","on");
                set(app.nEditField,"Editable","on");
                set(app.RowsEditField,"Editable","on");
                set(app.ColumnsEditField,"Editable","on")
            end
        end

        % Button pushed function: StopButton
        function stopDistribution(app, event)
            set(app.legHG,"AutoUpdate","on");
            set(app.ResetButton,"Enable","on");
            set(app.SampleButton,"Enable","on")
            set(app.PiButton,"Enable","on");
            set(app.nEditField,"Editable","on");
            set(app.RowsEditField,"Editable","on");
            set(app.ColumnsEditField,"Editable","on") 
            %set(app.histAx.Subtitle,"String",[" ";" "])
            clear app.points
            app.stopFlag = true;
        end

        % Button pushed function: ResetButton
        function resetAll(app, event)
            delete(app.histHG);
            delete(app.txtHG);
            delete(app.pointsHG);
            delete(app.patchHG);
            app.nEditField.Value = 0;
            app.allVals = [];
            app.cmat = ones(app.gridSize);
            viewOutcomes(app,app.cmat,app.cmap);
        end

        % Value changed function: RowsEditField
        function sizeChangeRows(app, event)
            delete(app.histHG);
            delete(app.txtHG);
            delete(app.pointsHG);

            value = app.RowsEditField.Value;
            if value <= app.gridSize(1)
                app.cmat = app.cmat(1:value,:);
            else
                app.cmat = [app.cmat;ones(value-app.gridSize(1),app.gridSize(2))];
            end
            app.gridSize(1) = value;
            viewOutcomes(app,app.cmat,app.cmap);

        end

        % Value changed function: ColumnsEditField
        function sizeChangeCols(app, event)

            delete(app.histHG);
            delete(app.txtHG);
            delete(app.pointsHG);

            value = app.ColumnsEditField.Value;
            if value <= app.gridSize(2)
                app.cmat= app.cmat(:,1:value);
            else
                app.cmat = [app.cmat,ones(app.gridSize(1),value-app.gridSize(2))];
            end
            app.gridSize(2) = value;
            viewOutcomes(app,app.cmat,app.cmap);


        end

        % Window button motion function: RandVarFig
        function detectMotion(app, event)
            pause(0)
            pt = app.probAx.CurrentPoint(1,1:2);
            isInAxes = pt(1) >= 0.5 && pt(1) <= app.gridSize(2)+0.5 && pt(2) >= 0.5 && pt(2) <= app.gridSize(1)+0.5;

            if isInAxes
                app.RandVarFig.Pointer = "hand";

                if app.clickFlag & app.RandVarFig.SelectionType == "normal"

                    pt = round(pt);
                    app.cmat(pt(2),pt(1)) = find(app.allVals == app.nEditField.Value);
                    set(app.probIm,"CData",app.cmat);
                end
            else
                app.RandVarFig.Pointer = "arrow";
            end

        end

        % Window button up function: RandVarFig
        function detectRelease(app, event)
            app.clickFlag = false;
        end

        % Window button down function: RandVarFig
        function detectClick(app, event)
            app.clickFlag = true;
            pt = app.probAx.CurrentPoint(1,1:2);
            isInAxes = pt(1) >= 0.5 && pt(1) <= app.gridSize(2)+0.5 && pt(2) >= 0.5 && pt(2) <= app.gridSize(1)+0.5;
            if isInAxes
                pt = round(pt);
                app.cmat(pt(2),pt(1)) = find(app.allVals == app.nEditField.Value);
                set(app.probIm,"CData",app.cmat);

            end
        end

        % Button pushed function: PiButton
        function estimatePi(app, event)
            resetAll(app, event)

            % Generate a circle in the grid/sample space.
            width = 501;
            height =  501; 
            radius = 250;  
            C = zeros(height,width);
            [x, y] = meshgrid(1:width, 1:height);
            C((x - width/2).^2 + (y - height/2).^2 <= radius.^2) = 1;

            app.gridSize = size(C);
            app.cmat = C+1;

            % Set up the the random variable corresponding to the circular
            % region and update the legend.
            app.RowsEditField.Value = size(C,1);
            app.ColumnsEditField.Value = size(C,2);
            vals = unique(double(C*4));
            for k = vals'
                app.nEditField.Value = k;
                newValue(app,event)
            end
 
            % Display the circle and the random variable assignments
            viewOutcomes(app,app.cmat,app.cmap);

        end

        % Close request function: RandVarFig
        function cleanup(app, event)
            
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RandVarFig = uifigure('Visible', 'off');
            app.RandVarFig.Name = 'Random Variables';
            app.RandVarFig.CloseRequestFcn = createCallbackFcn(app, @cleanup, true);
            app.RandVarFig.WindowButtonDownFcn = createCallbackFcn(app, @detectClick, true);
            app.RandVarFig.WindowButtonUpFcn = createCallbackFcn(app, @detectRelease, true);
            app.RandVarFig.WindowButtonMotionFcn = createCallbackFcn(app, @detectMotion, true);
            app.GridLayout = uigridlayout(app.RandVarFig);
            app.probAx = uiaxes(app.GridLayout);
            zlabel(app.probAx, 'Z')
            app.probAx.XTick = [];
            app.probAx.YTick = [];
            app.histAx = uiaxes(app.GridLayout);
            title(app.histAx, 'P(X = n)')
            zlabel(app.histAx, 'Z')
            app.histAx.XTick = [];
            app.histAx.YTick = [];
            app.patchAx = uiaxes(app.GridLayout);
            zlabel(app.patchAx, 'Z')
            app.patchAx.XTick = [];
            app.patchAx.YTick = [];
            app.SampleButton = uibutton(app.GridLayout, 'push');
            app.SampleButton.ButtonPushedFcn = createCallbackFcn(app, @sampleDistribution, true);
            app.SampleButton.Tooltip = {'After creating the random variable X by assigning values to the outcomes, sample thousand times to generate an empirical distribution of the random variable X.'};
            app.SampleButton.Text = 'Sample';
            app.TextArea = uitextarea(app.GridLayout);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'In this app, you will create a random variable X and generate its empirical distribution. The blocks in the probability space below represent outcomes. To create your random variable:'; ' 1. Enter a numeric value in the edit field for n. Click the mouse pointer over all the outcomes (blocks) to which you want to assign this value.'; ' 2. Repeat the first step by entering a new value n, or re-enter a previously created value to update any existing assignments.'; ''};
            app.StopButton = uibutton(app.GridLayout, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @stopDistribution, true);
            app.StopButton.Tooltip = {'Stop sampling.'};
            app.StopButton.Text = 'Stop';
            app.ProbabilityspaceLabel = uilabel(app.GridLayout);
            app.ProbabilityspaceLabel.Text = 'Probability space';
            app.ResetButton = uibutton(app.GridLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @resetAll, true);
            app.ResetButton.Text = 'Reset';
            app.nEditField = uieditfield(app.GridLayout, 'numeric');
            app.nEditField.ValueChangedFcn = createCallbackFcn(app, @newValue, true);
            app.nEditField.Tooltip = {'Enter a new or an existing value. This value is assigned to the outcomes you click in the probability space below. Each new value is represented by a unique color.'};
            app.nEditFieldLabel = uilabel(app.GridLayout);
            app.nEditFieldLabel.Text = 'n';
            app.RowsEditFieldLabel = uilabel(app.GridLayout);
            app.RowsEditFieldLabel.Text = 'Rows';
            app.RowsEditField = uieditfield(app.GridLayout, 'numeric');
            app.RowsEditField.RoundFractionalValues = 'on';
            app.RowsEditField.ValueDisplayFormat = '%i';
            app.RowsEditField.ValueChangedFcn = createCallbackFcn(app, @sizeChangeRows, true);
            app.RowsEditField.Tooltip = {'Number of rows in the grid.'};
            app.RowsEditField.Value = 10;
            app.ColumnsEditFieldLabel = uilabel(app.GridLayout);
            app.ColumnsEditFieldLabel.Text = 'Columns';
            app.ColumnsEditField = uieditfield(app.GridLayout, 'numeric');
            app.ColumnsEditField.RoundFractionalValues = 'on';
            app.ColumnsEditField.ValueDisplayFormat = '%i';
            app.ColumnsEditField.ValueChangedFcn = createCallbackFcn(app, @sizeChangeCols, true);
            app.ColumnsEditField.Tooltip = {'Number of columns in the grid.'};
            app.ColumnsEditField.Value = 10;
            app.PiButton = uibutton(app.GridLayout, 'push');
            app.PiButton.ButtonPushedFcn = createCallbackFcn(app, @estimatePi, true);
            app.PiButton.Tooltip = {'Load a predefined random variable. Study the random variable, and sample the probability space to estimate the value of π.'};
            app.PiButton.Text = 'Load π example';
            app.EnterLabel = uilabel(app.GridLayout);
            app.EnterLabel.Text = 'Enter';
            app.ShowParams = uicheckbox(app.GridLayout);
            app.ShowParams.Text = 'Show distribution parameters';
            % Show the figure after all components are created
            app.RandVarFig.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RandomVariableApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RandVarFig)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RandVarFig)
        end
    end
end